classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InsertT1KEditField             matlab.ui.control.NumericEditField
        InsertT1KLabel                 matlab.ui.control.Label
        InsertP1kPaEditField           matlab.ui.control.NumericEditField
        InsertP1kPaLabel               matlab.ui.control.Label
        InsertShockSpeedEditField      matlab.ui.control.NumericEditField
        InsertShockSpeedLabel          matlab.ui.control.Label
        SpecificHeatRatioGammaEditField  matlab.ui.control.NumericEditField
        SpecificHeatRatioGammaLabel    matlab.ui.control.Label
        FRAME4Label                    matlab.ui.control.Label
        FRAME3Label                    matlab.ui.control.Label
        FRAME2Label                    matlab.ui.control.Label
        FRAME1Label                    matlab.ui.control.Label
        UITable2_4                     matlab.ui.control.Table
        UITable2_3                     matlab.ui.control.Table
        UITable2_2                     matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        MachNumber2EditField           matlab.ui.control.EditField
        MachNumber2EditFieldLabel      matlab.ui.control.Label
        SelectdesiredparametersButtonGroup  matlab.ui.container.ButtonGroup
        DensityRatioButton             matlab.ui.control.RadioButton
        TemperatureRatioButton         matlab.ui.control.RadioButton
        PressureRatioButton            matlab.ui.control.RadioButton
        MachNumberButton               matlab.ui.control.RadioButton
        ResetButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        EditField                      matlab.ui.control.EditField
        MachNumberLabel                matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SelectdesiredtableButtonGroup  matlab.ui.container.ButtonGroup
        ReflectedShockWaveButton       matlab.ui.control.RadioButton
        NormalShockWaveButton          matlab.ui.control.RadioButton
        IsentropicFlowTableButton      matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % Get the user input Mach number
        % Constants
        gamma = 1.4;  % Ratio of specific heats
    if app.MachNumberButton.Value
        
        M = str2double(app.EditField.Value);
        if app.IsentropicFlowTableButton.Value
        app.InsertShockSpeedUsmsEditField.Visible = 'off';
        % Calculate isentropic flow properties
        p0_p = (1 + (gamma - 1) / 2 * M^2)^(gamma / (gamma - 1));
        rho0_rho = (1 + (gamma - 1) / 2 * M^2)^(1 / (gamma - 1));
        T0_T = (1 + (gamma - 1) / 2 * M^2);
        a0_a = (1 + (gamma - 1) / 2 * M^2).^(0.5);
        A_Astar = (1./M).*(2/(gamma+1)+(gamma-1)/(gamma+1)*M.^2).^((gamma+1)/(2*(gamma-1)));
        theta_rad = sqrt((gamma+1)/(gamma-1))*atan(sqrt((gamma-1)/(gamma+1)*(M^2-1)))-atan(sqrt(M^2-1));
        theta_deg = theta_rad*180/pi;
        

        % Create the isentropic flow table
        data = {'Mach Number','M', M;
                'Temperature Ratio ','T0_T', T0_T;
                'Pressure Ratio','p0_p', p0_p;
                'Density Ratio ','rho0/rho' ,rho0_rho;
                'Area Ratio ','a0/a', a0_a;
                'Throat Area Ratio','A/A*' ,A_Astar;
                'Angle ','Theta', theta_deg};
        app.UITable.Data = data;
             % Create and display the table
        elseif app.NormalShockWaveButton.Value
            app.InsertShockSpeedUsmsEditField.Visible = 'off';
        % Calculate normal shock wave properties
        M2 = sqrt(((gamma-1)*M.^2+2)./(2*gamma*M.^2-(gamma-1)));
        P2_P1 = (2*gamma.*M.^2-(gamma-1))./(gamma+1);  %P2/P1
        T2_T1 = ((2*gamma*M.^2-(gamma-1)).*(2+(gamma-1)*M.^2))./(((gamma+1)^2)*M.^2); %T2/T1
        rho2_rho1 = P2_P1./T2_T1;

        % Create the normal shock wave table
        data = {'Mach Number ','M1', M;
                'Mach Number ','M2', M2;
                'Pressure Ratio ','p2/p1', P2_P1;
                'Temperature Ratio ','T2/T1', T2_T1;
                'Density Ratio ','rho2/rho1', rho2_rho1};

        % Create and display the table
        app.UITable.Data = data;

         elseif app.ReflectedShockWaveButton.Value
             
                % Get the user input Mach number and deflection angle
                M1 = str2double(app.EditField.Value);
                 % Calculate the reflected shock wave properties
                theta = atan(sqrt((2 * (gamma + 1) * M1^2) / ((gamma - 1) * M1^2 + 2))) * 180 / pi;
                M2 = sqrt((2+(gamma-1)*M1^2*sin(theta)^2)/(2*gamma*M1^2-(gamma-1)*sin(theta)^2));
                P2_P1 = 1 + (2*gamma*M1^2*sin(theta)^2 - (gamma-1))/(gamma+1);
                T2_T1 = P2_P1 * (2*gamma*M1^2*sin(theta)^2 - (gamma-1) + 2)/(2+(gamma-1)*M1^2*sin(theta)^2);
                rho2_rho1 = P2_P1 / T2_T1;
                % Create the reflected shock wave table
                data = {'Mach Number (upstream)', 'M1', M1;
                        'Mach Number (downstream)', 'M2', M2;
                        'Pressure Ratio', 'P2/P1', P2_P1;
                        'Temperature Ratio', 'T2/T1', T2_T1;
                        'Density Ratio', 'rho2/rho1', rho2_rho1};
                 app.UITable.Data = data;
                 % Check the user's choice
        end
    elseif app.PressureRatioButton.Value
        app.InsertShockSpeedUsmsEditField.Visible = 'off';
        if app.IsentropicFlowTableButton.Value
            app.InsertShockSpeedUsmsEditField.Visible = 'off';
        p0_p = str2double(app.EditField.Value);
        Mnew = sqrt(1 + (gamma - 1)/nthroot(p0_p,1 + (gamma - 1)));
        M = Mnew;
        rho0_rho = (1 + (gamma - 1) / 2 * M^2)^(1 / (gamma - 1));
        T0_T = (1 + (gamma - 1) / 2 * M^2);
        a0_a = (1 + (gamma - 1) / 2 * M^2).^(0.5);
        A_Astar = (1./M).*(2/(gamma+1)+(gamma-1)/(gamma+1)*M.^2).^((gamma+1)/(2*(gamma-1)));
        theta_rad = sqrt((gamma+1)/(gamma-1))*atan(sqrt((gamma-1)/(gamma+1)*(M^2-1)))-atan(sqrt(M^2-1));
        theta_deg = theta_rad*180/pi;

        data = {'Mach Number','M', M;
                'Temperature Ratio ','T0_T', T0_T;
                'Pressure Ratio','p0_p', p0_p;
                'Density Ratio ','rho0/rho' ,rho0_rho;
                'Area Ratio ','a0/a', a0_a;
                'Throat Area Ratio','A/A*' ,A_Astar;
                'Angle ','Theta', theta_deg};
        app.UITable.Data = data;

        elseif app.NormalShockWaveButton.Value
            app.InsertShockSpeedUsmsEditField.Visible = 'off';
           P2_P1 = str2double(app.EditField.Value);
           M = sqrt(((P2_P1.*(gamma+1)) + (gamma-1)) ./ (2*gamma));
           M2 = sqrt(((gamma-1)*M.^2+2)./(2*gamma*M.^2-(gamma-1)));
           T2_T1 = ((2*gamma*M.^2-(gamma-1)).*(2+(gamma-1)*M.^2))./(((gamma+1)^2)*M.^2); %T2/T1
           rho2_rho1 = P2_P1./T2_T1;

           % Create the normal shock wave table
            data = {'Mach Number ','M1', M;
                'Mach Number ','M2', M2;
                'Pressure Ratio ','p2/p1', P2_P1;
                'Temperature Ratio ','T2/T1', T2_T1;
                'Density Ratio ','rho2/rho1', rho2_rho1};

            % Create and display the table
            app.UITable.Data = data;


        end
    elseif app.TemperatureRatioButton.Value
        if app.IsentropicFlowTableButton.Value
            app.InsertShockSpeedUsmsEditField.Visible = 'off';
        T0_T  = str2double(app.EditField.Value);
        Mnew = sqrt((2 / (gamma - 1)) * (T0_T - 1));
        M = Mnew;
        p0_p = (1 + (gamma - 1) / 2 * M^2)^(gamma / (gamma - 1));
        rho0_rho = (1 + (gamma - 1) / 2 * M^2)^(1 / (gamma - 1));
        a0_a = (1 + (gamma - 1) / 2 * M^2).^(0.5);
        A_Astar = (1./M).*(2/(gamma+1)+(gamma-1)/(gamma+1)*M.^2).^((gamma+1)/(2*(gamma-1)));
        theta_rad = sqrt((gamma+1)/(gamma-1))*atan(sqrt((gamma-1)/(gamma+1)*(M^2-1)))-atan(sqrt(M^2-1));
        theta_deg = theta_rad*180/pi;

        data = {'Mach Number','M', M;
                'Temperature Ratio ','T0_T', T0_T;
                'Pressure Ratio','p0_p', p0_p;
                'Density Ratio ','rho0/rho' ,rho0_rho;
                'Area Ratio ','a0/a', a0_a;
                'Throat Area Ratio','A/A*' ,A_Astar;
                'Angle ','Theta', theta_deg};
        app.UITable.Data = data;

        elseif app.NormalShockWaveButton.Value
           T2_T1 = str2double(app.EditField.Value);
           M = sqrt((gamma * T2_T1 - gamma - 1) / (2 * gamma - gamma - 1));
           P2_P1 = (2*gamma.*M.^2-(gamma-1))./(gamma+1);
           M2 = sqrt(((gamma-1)*M.^2+2)./(2*gamma*M.^2-(gamma-1)));
           %T2_T1 = ((2*gamma*M.^2-(gamma-1)).*(2+(gamma-1)*M.^2))./(((gamma+1)^2)*M.^2); %T2/T1
           rho2_rho1 = P2_P1./T2_T1;

           % Create the normal shock wave table
            data = {'Mach Number ','M1', M;
                'Mach Number ','M2', M2;
                'Pressure Ratio ','p2/p1', P2_P1;
                'Temperature Ratio ','T2/T1', T2_T1;
                'Density Ratio ','rho2/rho1', rho2_rho1};

            % Create and display the table
            app.UITable.Data = data;


        end

    elseif app.DensityRatioButton.Value
        if app.IsentropicFlowTableButton.Value
        rho0_rho  = str2double(app.EditField.Value);
        Mnew = sqrt(((rho0_rho^(gamma - 1)) - 1) / ((gamma - 1) / 2));
        M = Mnew;
        p0_p = (1 + (gamma - 1) / 2 * M^2)^(gamma / (gamma - 1));
        T0_T = (1 + (gamma - 1) / 2 * M^2);
        rho0_rho = (1 + (gamma - 1) / 2 * M^2)^(1 / (gamma - 1));
        a0_a = (1 + (gamma - 1) / 2 * M^2).^(0.5);
        A_Astar = (1./M).*(2/(gamma+1)+(gamma-1)/(gamma+1)*M.^2).^((gamma+1)/(2*(gamma-1)));
        theta_rad = sqrt((gamma+1)/(gamma-1))*atan(sqrt((gamma-1)/(gamma+1)*(M^2-1)))-atan(sqrt(M^2-1));
        theta_deg = theta_rad*180/pi;

        data = {'Mach Number','M', M;
                'Temperature Ratio ','T0_T', T0_T;
                'Pressure Ratio','p0_p', p0_p;
                'Density Ratio ','rho0/rho' ,rho0_rho;
                'Area Ratio ','a0/a', a0_a;
                'Throat Area Ratio','A/A*' ,A_Astar;
                'Angle ','Theta', theta_deg};
        app.UITable.Data = data;
        elseif app.NormalShockWaveButton.Value
            app.MachNumber2EditField.Visible = "on";
            app.MachNumber2EditFieldLabel.Visible = "on";
           rho2_rho1 = str2double(app.EditField.Value);
           M2 = str2double(app.MachNumber2EditField.Value);
           M = sqrt((M2^2*(2*gamma - gamma + 1) - 2) / (gamma - 1));
           P2_P1 = (2*gamma.*M.^2-(gamma-1))./(gamma+1);  %P2/P1
           T2_T1 = ((2*gamma*M.^2-(gamma-1)).*(2+(gamma-1)*M.^2))./(((gamma+1)^2)*M.^2); %T2/T1

           % Create the normal shock wave table
            data = {'Mach Number ','M1', M;
                'Mach Number ','M2', M2;
                'Pressure Ratio ','p2/p1', P2_P1;
                'Temperature Ratio ','T2/T1', T2_T1;
                'Density Ratio ','rho2/rho1', rho2_rho1};

            % Create and display the table
            app.UITable.Data = data;

        end


    end


   if app.ReflectedShockWaveButton.Value
            % Get the user input 
            y = app.SpecificHeatRatioGammaEditField.Value;
            Us = app.InsertShockSpeedEditField.Value;
            P1 = app.InsertP1kPaEditField.Value;
            T1 = app.InsertT1KEditField.Value;
            P1 = P1 * 1000;
%--------------------------------------------------------------------------

% Before reflection

% Change reference frame
V1p = Us;
M1p = V1p / sqrt(y*287*T1);

% Solve region 2
M2p = sqrt((((y-1)*M1p^2)+2)/((2*y*M1p^2)-(y-1)));
P2 = P1*((2*y*M1p^2)-(y-1))/(y+1);
T2 = T1*(((2*y*M1p^2)-(y-1))*(2+((y-1)*M1p^2)))/(((y+1)^2)*M1p^2);

V2p = M2p * sqrt(y*287*T2);
V2 = Us - V2p;
M2 = V2 / sqrt(y*287*T2);

%--------------------------------------------------------------------------

% After Reflection - Iteration

n = 0;
M2pa = 1;
M2pb = 5.5;
R = 0.6180339880;
l = R*(M2pb - M2pa);
M2pc = M2pb - l;
M2pd = M2pa + l;

while l>0.0001

    n = n + 1;

    V2pa = M2pa * sqrt(y*287*T2);
    Ua = V2pa - V2;
    M3pa = sqrt((((y-1)*M2pa^2)+2)/((2*y*M2pa^2)-(y-1)));
    T3a = T2*(((2*y*M2pa^2)-(y-1))*(2+((y-1)*M2pa^2)))/(((y+1)^2)*M2pa^2);
    V3pa = M3pa * sqrt(y*287*T3a);
    V3a = Ua - V3pa;
    
    V2pb = M2pb * sqrt(y*287*T2);
    Ub = V2pb - V2;
    M3pb = sqrt((((y-1)*M2pb^2)+2)/((2*y*M2pb^2)-(y-1)));
    T3b = T1*(((2*y*M2pb^2)-(y-1))*(2+((y-1)*M2pb^2)))/(((y+1)^2)*M2pb^2);
    V3pb = M3pb * sqrt(y*287*T3b);
    V3b = Ub - V3pb;
    
    V2pc = M2pc * sqrt(y*287*T2);
    Uc = V2pc - V2;
    M3pc = sqrt((((y-1)*M2pc^2)+2)/((2*y*M2pc^2)-(y-1)));
    T3c = T2*(((2*y*M2pc^2)-(y-1))*(2+((y-1)*M2pc^2)))/(((y+1)^2)*M2pc^2);
    V3pc = M3pc * sqrt(y*287*T3c);
    V3c = Uc - V3pc;

    V2pd = M2pd * sqrt(y*287*T2);
    Ud = V2pd - V2;
    M3pd = sqrt((((y-1)*M2pd^2)+2)/((2*y*M2pd^2)-(y-1)));
    T3d = T2*(((2*y*M2pd^2)-(y-1))*(2+((y-1)*M2pd^2)))/(((y+1)^2)*M2pd^2);
    V3pd = M3pd * sqrt(y*287*T3d);
    V3d = Ud - V3pd;
    V3 = 0;

    if (abs(V3c) < abs(V3d))
        M2pb = M2pd;
        M2pd = M2pc;
        l = R*(M2pb - M2pa);
        M2pc = M2pb - l;
    else
        M2pa = M2pc;
        M2pc = M2pd;
        l = R*(M2pb - M2pa);
        M2pd = M2pa + l;
    end
end

P3 = P2*((2*y*M2pc^2)-(y-1))/(y+1);
                        
               
                % Create the reflected shock wave table
                data = {'V2', 'm/s', V2;
                        'P2', 'kPa', P2/1000;
                        'T2', 'K', T2;};
                 app.UITable2.Data = data;
                data2 = {'V1', 'm/s', V1p;
                        'P1', 'kPa', P1/1000;
                        'T1', 'K', T1;
                        'V2', 'm/s', V2p;
                        'P2','kPa',P2/1000;
                        'T2','K',T2};
                app.UITable2_2.Data = data2;

                data3 = {'V3', 'm/s', V3;
                        'P3', 'kPa', P3/1000;
                        'T3', 'K', T3c;
                        'Usr', 'm/s', Uc;
                        'V2', 'm/s', V2p;
                        'P2','kPa',P2/1000;
                        'T2','K',T2};
                app.UITable2_3.Data = data3;

                data4 = {'V3', 'm/s', V3pc;
                        'P3', 'kPa', P3/1000;
                        'T3', 'K', T3c;
                        'V2r', 'm/s', V2pc;
                        'P2','kPa',P2/1000;
                        'T2','K',T2};
                app.UITable2_4.Data = data4;

   end
        
        % Code that executes before app deletion
function delete(app)
    % Delete UIFigure when app is deleted
    delete(app.UIFigure);
    
    % Delete ResetButton
    delete(app.ResetButton);
end
    
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data = {};
            app.UITable2.Data ={};
            app.UITable2_2.Data = {};
            app.UITable2_3.Data = {};
            app.UITable2_4.Data = {};
        end

        % Selection changed function: SelectdesiredparametersButtonGroup
        function SelectdesiredparametersButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectdesiredparametersButtonGroup.SelectedObject;
             if selectedButton == app.MachNumberButton
             app.MachNumberLabel.Text = 'Mach Number:';
             elseif selectedButton == app.PressureRatioButton
             app.MachNumberLabel.Text = 'Pressure Ratio:';
             elseif selectedButton == app.DensityRatioButton
             app.MachNumberLabel.Text = 'Density Ratio:';
             elseif selectedButton == app.TemperatureRatioButton
             app.MachNumberLabel.Text = 'Temperature Ratio:';
             end
        end

        % Selection changed function: SelectdesiredtableButtonGroup
        function SelectdesiredtableButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectdesiredtableButtonGroup.SelectedObject;
            if selectedButton == app.NormalShockWaveButton && app.SelectdesiredparametersButtonGroup.SelectedObject == app.DensityRatioButton
                
                app.MachNumber2EditField.Visible = "on";
                app.MachNumber2EditFieldLabel.Visible = "on";

                
            end
            if selectedButton == app.ReflectedShockWaveButton
                app.SpecificHeatRatioGammaEditField.Visible="on";
                app.SpecificHeatRatioGammaLabel.Visible="on";
                app.InsertShockSpeedEditField.Visible="on";
                app.InsertShockSpeedLabel.Visible="on";
                app.InsertT1KEditField.Visible="on";
                app.InsertT1KLabel.Visible="on";
                app.InsertP1kPaEditField.Visible="on";
                app.InsertP1kPaLabel.Visible="on";
                app.MachNumberLabel.Visible="off";
                app.EditField.Visible="off";
                app.UITable.Visible="off";
                app.UITable2.Visible="on";
                app.UITable2_2.Visible="on";
                app.UITable2_3.Visible="on";
                app.UITable2_4.Visible="on";
                app.SelectdesiredparametersButtonGroup.Visible="off";
                app.FRAME1Label.Visible="on";
                app.FRAME2Label.Visible="on";
                app.FRAME3Label.Visible="on";
                app.FRAME4Label.Visible="on";
                app.MachNumber2EditField.Visible="off";
                app.MachNumber2EditFieldLabel.Visible="off";
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectdesiredtableButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectdesiredtableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectdesiredtableButtonGroupSelectionChanged, true);
            app.SelectdesiredtableButtonGroup.Title = 'Select desired table';
            app.IsentropicFlowTableButton = uiradiobutton(app.SelectdesiredtableButtonGroup);
            app.IsentropicFlowTableButton.Text = 'Isentropic Flow Table';
            app.IsentropicFlowTableButton.Value = true;
            app.NormalShockWaveButton = uiradiobutton(app.SelectdesiredtableButtonGroup);
            app.NormalShockWaveButton.Text = 'Normal Shock Wave';
            app.ReflectedShockWaveButton = uiradiobutton(app.SelectdesiredtableButtonGroup);
            app.ReflectedShockWaveButton.Text = 'Reflected Shock Wave';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MachNumberLabel = uilabel(app.UIFigure);
            app.MachNumberLabel.Text = 'Mach Number :';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectdesiredparametersButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectdesiredparametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectdesiredparametersButtonGroupSelectionChanged, true);
            app.SelectdesiredparametersButtonGroup.Title = 'Select desired parameters :';
            app.MachNumberButton = uiradiobutton(app.SelectdesiredparametersButtonGroup);
            app.MachNumberButton.Text = 'Mach Number';
            app.MachNumberButton.Value = true;
            app.PressureRatioButton = uiradiobutton(app.SelectdesiredparametersButtonGroup);
            app.PressureRatioButton.Text = 'Pressure Ratio';
            app.TemperatureRatioButton = uiradiobutton(app.SelectdesiredparametersButtonGroup);
            app.TemperatureRatioButton.Text = 'Temperature Ratio';
            app.DensityRatioButton = uiradiobutton(app.SelectdesiredparametersButtonGroup);
            app.DensityRatioButton.Text = 'Density Ratio';
            app.MachNumber2EditFieldLabel = uilabel(app.UIFigure);
            app.MachNumber2EditFieldLabel.Visible = 'off';
            app.MachNumber2EditFieldLabel.Text = 'Mach Number 2 :';
            app.MachNumber2EditField = uieditfield(app.UIFigure, 'text');
            app.MachNumber2EditField.Visible = 'off';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.UITable2_2 = uitable(app.UIFigure);
            app.UITable2_2.ColumnName = '';
            app.UITable2_2.RowName = {};
            app.UITable2_2.Visible = 'off';
            app.UITable2_3 = uitable(app.UIFigure);
            app.UITable2_3.ColumnName = '';
            app.UITable2_3.RowName = {};
            app.UITable2_3.Visible = 'off';
            app.UITable2_4 = uitable(app.UIFigure);
            app.UITable2_4.ColumnName = '';
            app.UITable2_4.RowName = {};
            app.UITable2_4.Visible = 'off';
            app.FRAME1Label = uilabel(app.UIFigure);
            app.FRAME1Label.Visible = 'off';
            app.FRAME1Label.Text = 'FRAME 1';
            app.FRAME2Label = uilabel(app.UIFigure);
            app.FRAME2Label.Visible = 'off';
            app.FRAME2Label.Text = 'FRAME 2';
            app.FRAME3Label = uilabel(app.UIFigure);
            app.FRAME3Label.Visible = 'off';
            app.FRAME3Label.Text = 'FRAME 3';
            app.FRAME4Label = uilabel(app.UIFigure);
            app.FRAME4Label.Visible = 'off';
            app.FRAME4Label.Text = 'FRAME 4';
            app.SpecificHeatRatioGammaLabel = uilabel(app.UIFigure);
            app.SpecificHeatRatioGammaLabel.Visible = 'off';
            app.SpecificHeatRatioGammaLabel.Text = 'Specific Heat Ratio (Gamma) :';
            app.SpecificHeatRatioGammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificHeatRatioGammaEditField.Visible = 'off';
            app.InsertShockSpeedLabel = uilabel(app.UIFigure);
            app.InsertShockSpeedLabel.Visible = 'off';
            app.InsertShockSpeedLabel.Text = 'Insert Shock Speed :';
            app.InsertShockSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertShockSpeedEditField.Visible = 'off';
            app.InsertP1kPaLabel = uilabel(app.UIFigure);
            app.InsertP1kPaLabel.Visible = 'off';
            app.InsertP1kPaLabel.Text = 'Insert P1 (kPa) :';
            app.InsertP1kPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertP1kPaEditField.Visible = 'off';
            app.InsertT1KLabel = uilabel(app.UIFigure);
            app.InsertT1KLabel.Visible = 'off';
            app.InsertT1KLabel.Text = 'Insert T1 (K) :';
            app.InsertT1KEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertT1KEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end